classdef DSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ShowDevicesButton               matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        SetInputChannelEditFieldLabel   matlab.ui.control.Label
        SetInputChannelEditField        matlab.ui.control.NumericEditField
        SetOutputChannelEditFieldLabel  matlab.ui.control.Label
        SetOutputChannelEditField       matlab.ui.control.NumericEditField
        ChooseChannelsButton            matlab.ui.control.Button
        CreateanaudiosessionButton      matlab.ui.control.Button
        SamplingRateinHzListBoxLabel    matlab.ui.control.Label
        SamplingRateinHzListBox         matlab.ui.control.ListBox
        CenterPanel                     matlab.ui.container.Panel
        FFTPlotsPanel                   matlab.ui.container.Panel
        VoicePanel                      matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        FreqLabel                       matlab.ui.control.Label
        LowPassSlider                   matlab.ui.control.Slider
        PassbandEdgeFrequencyinHzLabel  matlab.ui.control.Label
        EdgeFreq                        matlab.ui.control.NumericEditField
        peaktopeakrippleSliderLabel     matlab.ui.control.Label
        peaktopeakrippleSlider          matlab.ui.control.Slider
        peaktopeakrippleEditFieldLabel  matlab.ui.control.Label
        peaktopeakrippleEditField       matlab.ui.control.NumericEditField
        StopbandAttenuationSliderLabel  matlab.ui.control.Label
        StopbandAttenuationSlider       matlab.ui.control.Slider
        StopbandAttenuationindBEditFieldLabel  matlab.ui.control.Label
        StopbandAttenuationindBEditField  matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Devices; % Devices in computer
        input_channel; %Chosen Channel
        output_channel; %Chosen Output channel
        chosen_device; %Chosen device
        s; %Audio session
        Fs = 1000; % Sampling Rate
        Fp = 0.2; % Basspand frequency
        Ap = 0.1; % Ripple
        Ast = 40; % Attenuation
        filter; %Filter
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowDevicesButton
        function ShowDevicesButtonPushed(app, event)
            app.Devices = daq.getDevices;
            app.Label.Text = sprintf(app.Devices(1).Model);
            app.Label_2.Text = sprintf(app.Devices(2).Model);
            app.Label_3.Text = sprintf(app.Devices(3).Model);
            app.Label_4.Text = sprintf(app.Devices(4).Model);
            app.Label_5.Text = sprintf(app.Devices(5).Model);
        end

        % Value changed function: SetInputChannelEditField
        function SetInputChannelEditFieldValueChanged(app, event)
            value = app.SetInputChannelEditField.Value;
            app.input_channel = value;
        end

        % Value changed function: SetOutputChannelEditField
        function SetOutputChannelEditFieldValueChanged(app, event)
            value = app.SetOutputChannelEditField.Value;
            app.output_channel = value;
        end

        % Button pushed function: ChooseChannelsButton
        function ChooseChannelsButtonPushed(app, event)
            app.chosen_device = app.Devices(app.input_channel);
        end

        % Button pushed function: CreateanaudiosessionButton
        function CreateanaudiosessionButtonPushed(app, event)
            app.s = daq.createSession(app.chosen_device.Vendor.ID);
            addAudioInputChannel(app.s, app.chosen_device.ID, 1:2);
            app.s.IsContinuous = false;
            app.s.UseStandardSampleRates = false;
            app.s.Rate = 8000;
            app.s.DurationInSeconds = 1;
            app.filter = helper_filter_create(app.Fp, app.Ap, app.Ast, app.Fs);
            
            ax1 = uiaxes('Parent',app.FFTPlotsPanel);
            hp = plot(ax1,zeros(1000,1));
            ax1.Title.String = "Discrete FFT Plot";
            ax1.XLabel.String = ('Frequency (Hz)');
            ax1.YLabel.String = ('|Y(f)|');
            
            ax2 = uiaxes('Parent',app.VoicePanel);
            hp2 = plot(ax2,zeros(1000,1));
            ax2.Title.String = "Voice Signal";
            ax2.XLabel.String = ('Samples');
            ax2.YLabel.String = ('Signal');

            plotFFT = @(src, event) helper_continuous_fft(event.Data, app.Fs, src.Rate, hp, app.filter);
            plotSignal = @(src, event) helper_continuous_signal(event.Data, app.Fs, src.Rate,hp2);

            addlistener(app.s, 'DataAvailable', plotFFT);
            addlistener(app.s, "DataAvailable", plotSignal);

            startBackground(app.s);
        end

        % Value changed function: SamplingRateinHzListBox
        function SamplingRateinHzListBoxValueChanged(app, event)
            value = app.SamplingRateinHzListBox.Value;
            chosen_item =  str2double(value);
            switch chosen_item
                case 1000
                    app.Fs = 1000;
                case 2000
                    app.Fs = 2000; 
                case 4000
                    app.Fs = 4000;
                case 8000
                    app.Fs = 8000;
                case 16000
                    app.Fs = 16000;
            end
            
            app.LowPassSlider.Limits = [0 app.Fs/2];
        end

        % Value changed function: LowPassSlider
        function LowPassSliderValueChanged(app, event)
            value = app.LowPassSlider.Value;
            app.EdgeFreq.Value = value;
            app.Fp = value;
            app.filter = helper_filter_create(app.Fp, app.Ap, app.Ast, app.Fs);
        end

        % Value changed function: peaktopeakrippleSlider
        function peaktopeakrippleSliderValueChanged(app, event)
            value = app.peaktopeakrippleSlider.Value;
            app.peaktopeakrippleEditField.Value = value;
            app.Ap = value;
            app.filter = helper_filter_create(app.Fp, app.Ap, app.Ast, app.Fs);
        end

        % Value changed function: StopbandAttenuationSlider
        function StopbandAttenuationSliderValueChanged(app, event)
            value = app.StopbandAttenuationSlider.Value;
            app.StopbandAttenuationindBEditField.Value = value;
            app.Ast = value;
            app.filter = helper_filter_create(app.Fp, app.Ap, app.Ast, app.Fs);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {879, 879, 879};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {879, 879};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {263, '1x', 433};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ShowDevicesButton = uibutton(app.LeftPanel, 'push');
            app.ShowDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDevicesButtonPushed, true);
            app.ShowDevicesButton.Text = 'Show Devices';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '';
            app.SetInputChannelEditFieldLabel = uilabel(app.LeftPanel);
            app.SetInputChannelEditFieldLabel.Text = 'Set Input Channel';
            app.SetInputChannelEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetInputChannelEditField.ValueChangedFcn = createCallbackFcn(app, @SetInputChannelEditFieldValueChanged, true);
            app.SetOutputChannelEditFieldLabel = uilabel(app.LeftPanel);
            app.SetOutputChannelEditFieldLabel.Text = 'Set Output Channel';
            app.SetOutputChannelEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetOutputChannelEditField.ValueChangedFcn = createCallbackFcn(app, @SetOutputChannelEditFieldValueChanged, true);
            app.ChooseChannelsButton = uibutton(app.LeftPanel, 'push');
            app.ChooseChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseChannelsButtonPushed, true);
            app.ChooseChannelsButton.Text = 'Choose Channels';
            app.CreateanaudiosessionButton = uibutton(app.LeftPanel, 'push');
            app.CreateanaudiosessionButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanaudiosessionButtonPushed, true);
            app.CreateanaudiosessionButton.Text = 'Create an audio session';
            app.SamplingRateinHzListBoxLabel = uilabel(app.LeftPanel);
            app.SamplingRateinHzListBoxLabel.Text = 'Sampling Rate in Hz,';
            app.SamplingRateinHzListBox = uilistbox(app.LeftPanel);
            app.SamplingRateinHzListBox.Items = {'1000', '2000', '4000', '8000', '16000'};
            app.SamplingRateinHzListBox.ValueChangedFcn = createCallbackFcn(app, @SamplingRateinHzListBoxValueChanged, true);
            app.SamplingRateinHzListBox.Value = '1000';
            app.CenterPanel = uipanel(app.GridLayout);
            app.FFTPlotsPanel = uipanel(app.CenterPanel);
            app.FFTPlotsPanel.Title = 'Plots';
            app.VoicePanel = uipanel(app.CenterPanel);
            app.VoicePanel.Title = 'Panel';
            app.RightPanel = uipanel(app.GridLayout);
            app.FreqLabel = uilabel(app.RightPanel);
            app.FreqLabel.Text = 'Freq';
            app.LowPassSlider = uislider(app.RightPanel);
            app.LowPassSlider.Limits = [0 1000];
            app.LowPassSlider.ValueChangedFcn = createCallbackFcn(app, @LowPassSliderValueChanged, true);
            app.PassbandEdgeFrequencyinHzLabel = uilabel(app.RightPanel);
            app.PassbandEdgeFrequencyinHzLabel.Text = 'Passband Edge Frequency in Hz';
            app.EdgeFreq = uieditfield(app.RightPanel, 'numeric');
            app.peaktopeakrippleSliderLabel = uilabel(app.RightPanel);
            app.peaktopeakrippleSliderLabel.Text = 'peak-to-peak ripple';
            app.peaktopeakrippleSlider = uislider(app.RightPanel);
            app.peaktopeakrippleSlider.Limits = [0.01 2];
            app.peaktopeakrippleSlider.ValueChangedFcn = createCallbackFcn(app, @peaktopeakrippleSliderValueChanged, true);
            app.peaktopeakrippleSlider.Value = 0.01;
            app.peaktopeakrippleEditFieldLabel = uilabel(app.RightPanel);
            app.peaktopeakrippleEditFieldLabel.Text = 'peak-to-peak ripple';
            app.peaktopeakrippleEditField = uieditfield(app.RightPanel, 'numeric');
            app.StopbandAttenuationSliderLabel = uilabel(app.RightPanel);
            app.StopbandAttenuationSliderLabel.Text = 'Stopband Attenuation';
            app.StopbandAttenuationSlider = uislider(app.RightPanel);
            app.StopbandAttenuationSlider.Limits = [20 70];
            app.StopbandAttenuationSlider.ValueChangedFcn = createCallbackFcn(app, @StopbandAttenuationSliderValueChanged, true);
            app.StopbandAttenuationSlider.Value = 20;
            app.StopbandAttenuationindBEditFieldLabel = uilabel(app.RightPanel);
            app.StopbandAttenuationindBEditFieldLabel.Text = 'Stopband Attenuation in dB';
            app.StopbandAttenuationindBEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end